classdef FinproSinsisMatlabSelesai < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        AudioInputTab                 matlab.ui.container.Tab
        SamplingFrequencyEditField    matlab.ui.control.NumericEditField
        SamplingFrequencyEditFieldLabel  matlab.ui.control.Label
        StopButton                    matlab.ui.control.Button
        kHzLabel                      matlab.ui.control.Label
        CompressionMethodEditFieldLabel  matlab.ui.control.Label
        CompressionMethodEditField    matlab.ui.control.EditField
        PlayUnfilteredAudioButton     matlab.ui.control.Button
        StatusEditFieldLabel          matlab.ui.control.Label
        StatusEditField               matlab.ui.control.EditField
        SelectFileButton              matlab.ui.control.Button
        InputAudioLabel               matlab.ui.control.Label
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        FilterDesignTab               matlab.ui.container.Tab
        BandpassFilterDesignLabel     matlab.ui.control.Label
        CutoffFrequencyEditField_2    matlab.ui.control.NumericEditField
        SamplingFrequencyEditField_2  matlab.ui.control.NumericEditField
        SamplingFrequencyEditField_2Label  matlab.ui.control.Label
        kHzLabel_4                    matlab.ui.control.Label
        kHzLabel_3                    matlab.ui.control.Label
        DesignFilterButton            matlab.ui.control.Button
        kHzLabel_2                    matlab.ui.control.Label
        OrderEditField                matlab.ui.control.NumericEditField
        OrderEditFieldLabel           matlab.ui.control.Label
        CutoffFrequencyEditField      matlab.ui.control.NumericEditField
        CutoffFrequencyLabel          matlab.ui.control.Label
        WindowDropDown                matlab.ui.control.DropDown
        WindowDropDownLabel           matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
        AudioOutputTab                matlab.ui.container.Tab
        GraphModeButtonGroup          matlab.ui.container.ButtonGroup
        MagnitudeindBmButton          matlab.ui.control.RadioButton
        TimeandFrequncyButton         matlab.ui.control.RadioButton
        StopButton_2                  matlab.ui.control.Button
        PlayFilteredAudioButton       matlab.ui.control.Button
        StatusEditField_2Label        matlab.ui.control.Label
        StatusEditField_2             matlab.ui.control.EditField
        ApplyDesignedFilterButton     matlab.ui.control.Button
        UIAxes_6                      matlab.ui.control.UIAxes
        UIAxes_5                      matlab.ui.control.UIAxes
        UIAxes_4                      matlab.ui.control.UIAxes
        UIAxes_3                      matlab.ui.control.UIAxes
        FIRBandPassFilterLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        audioplayerObj
        audioplayerObjF
        audioData
        fs
        h
        t
        max_magnitude
    end
    
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add the folder of the mlapp file to the MATLAB path
            addpath(fileparts(which(mfilename)));
            
            % Set the image source for the UIImage component
            app.UIImage.ImageSource = 'logo putih.png'; % Replace with your actual image file name
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            % Prompt the user to select an audio file
            [filename, pathname] = uigetfile({'*.wav;*.mp3;*.flac;*.ogg;*.m4a', 'Audio Files (*.wav, *.mp3, *.flac, *.ogg, *.m4a)'}, 'Select an Audio File');
            
            % Get the full path of the selected file
            if filename ~= 0
                fullpath = fullfile(pathname, filename);
    
            % Read the audio file
                [audioData, fs] = audioread(fullpath);
                audioInfo = audioinfo(fullpath);
                app.audioData = audioData;
                app.fs = fs;
                
            % Display the compression method and sampling frequency
                app.CompressionMethodEditField.Value = audioInfo.CompressionMethod;
                app.SamplingFrequencyEditField.Value = fs/1000;
                app.SamplingFrequencyEditField_2.Value = fs/1000;
    
            % Create a time vector based on the sampling frequency
                t = (0:length(audioData)-1) / fs;
                app.t = t;
    
            % Plot the audio signal in time domain
                plot(app.UIAxes_2, t, audioData);
                xlim(app.UIAxes_2, [0, max(t)]);
                grid(app.UIAxes_2, 'on');
                
            % Perform FFT to get the frequency domain representation
                n = length(audioData);
                f = (0:n-1)*(fs/n) / 1000;
                y = fft(audioData);
                magnitude = abs(y);
                max_magnitude = max(max(magnitude));
                app.max_magnitude = max_magnitude;
    
            % Plot the audio signal in frequency domain
                plot(app.UIAxes, f, magnitude);
                xlim(app.UIAxes, [0, fs/2000]);
                xticks(app.UIAxes, 0:1:fs/2000);
                xticklabels(app.UIAxes, string(0:1:fs/2000));
                ylim(app.UIAxes, [0, max_magnitude]);
                grid(app.UIAxes, 'on');
                
                app.StatusEditField.Value = "Audio input successful.";
                
            % Create an audioplayer object
                app.audioplayerObj = audioplayer(audioData, fs);
            
            % Enable Play, Stop, and Design Filter button
                app.PlayUnfilteredAudioButton.Enable = 'on';
                app.StopButton.Enable = 'on';
                app.DesignFilterButton.Enable = 'on';
            end
        end

        % Button pushed function: PlayUnfilteredAudioButton
        function PlayUnfilteredAudioButtonPushed(app, event)
            % Play the audioplayer object
            play(app.audioplayerObj);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Stop the audioplayer object
            stop(app.audioplayerObj);
        end

        % Callback function
        function FilterTypeDropDownValueChanged(app, event)

        end

        % Button pushed function: DesignFilterButton
        function DesignFilterButtonPushed(app, event)
            % Initialize filter spesifications
            fs = app.fs / 1000;
            N = app.OrderEditField.Value;
            window = app.WindowDropDown.Value;
            wc1 = 2 * pi * app.CutoffFrequencyEditField.Value / fs;   % Frekuensi cutoff bawah untuk bandpass
            wc2 = 2 * pi * app.CutoffFrequencyEditField_2.Value / fs; % Frekuensi cutoff atas untuk bandpass
            
            % Making sure N is even
            if mod(N, 2) == 1
                N = N + 1;
            end
            
            % Ideal impulse response of the BANDPASS filter
            n = 0:N;
            % Langsung menggunakan formula untuk bandpass filter
            hd = (sin(wc2*(n-(N/2))) - sin(wc1*(n-(N/2)))) ./ (pi*(n-(N/2)));
            % Menangani kasus ketika (n-(N/2)) = 0 untuk menghindari pembagian dengan nol
            % Ini adalah nilai tengah dari respons impuls
            if mod(N,2) == 0 % N adalah genap, jadi N/2 adalah integer
                center_index = N/2 + 1; % Indeks MATLAB dimulai dari 1
                if center_index <= length(hd)
                    hd(center_index) = (wc2 - wc1) / pi;
                end
            else
                % Jika N adalah ganjil (setelah penyesuaian N = N+1, maka N awal ganjil)
                % N/2 akan memiliki .5, jadi n-(N/2) tidak akan pernah tepat 0 jika n adalah integer.
                % Namun, karena kita memastikan N genap di atas, blok ini secara teknis tidak akan tercapai
                % untuk perhitungan hd(N/2+1).
                % Jika ada keraguan, logika di atas dengan center_index sudah cukup.
            end
            
            
            % Design filters using selected windows
            switch window
                case 'Rectangular'
                    win = rectwin(N+1);
                case 'Hann'
                    win = hann(N+1);
                case 'Hamming'
                    win = hamming(N+1);
                case 'Blackman'
                    win = blackman(N+1);
                otherwise % Tambahkan kasus default jika diperlukan
                    warning('Tipe window tidak dikenal, menggunakan Rectangular sebagai default.');
                    win = rectwin(N+1);
            end
            
            % Apply window to the ideal impulse response
            % Pastikan win adalah vektor baris jika hd adalah vektor baris, atau sebaliknya.
            % Fungsi window (hann, hamming, dll.) biasanya mengembalikan vektor kolom.
            % Jika hd adalah vektor baris (hasil dari n=0:N), maka win perlu ditranspose.
            h = hd .* win';
            app.h = h;
            
            % Frequency response of the filters
            [H, w] = freqz(h, 1, 1024, fs * 1000);
            
            % Plot the magnitude response
            plot(app.UIAxes2, w / 1000, 20*log10(abs(H)));
            xlim(app.UIAxes2, [0, fs/2]);
            xticks(app.UIAxes2, 0:1:fs/2);
            xticklabels(app.UIAxes2, string(0:1:fs/2));
            ylim(app.UIAxes2, [-100, 10]); % Mengubah batas bawah y untuk visualisasi yang lebih baik
            grid(app.UIAxes2, 'on');
            title(app.UIAxes2, 'Bandpass Filter Magnitude Response'); % Menambahkan judul plot
            xlabel(app.UIAxes2, 'Frequency (kHz)');
            ylabel(app.UIAxes2, 'Magnitude (dB)');
            
            % Enable Apply Filter button
            app.ApplyDesignedFilterButton.Enable = 'on';
        end

        % Button pushed function: ApplyDesignedFilterButton
        function ApplyDesignedFilterButtonPushed(app, event)
            % Clear plot
            set(app.UIAxes_3.Children, 'Visible', 'off');
            set(app.UIAxes_4.Children, 'Visible', 'off');
            set(app.UIAxes_5.Children, 'Visible', 'off');
            set(app.UIAxes_6.Children, 'Visible', 'off');
            
            app.GraphModeButtonGroup.SelectedObject = app.TimeandFrequncyButton;
            app.UIAxes_3.Visible = 'on';
            app.UIAxes_4.Visible = 'on';
            app.UIAxes_5.Visible = 'off';
            app.UIAxes_6.Visible = 'off';

            % Take parameters
            h = app.h;
            t = app.t;
            fs = app.fs;
            audioData = app.audioData;
            max_magnitude = app.max_magnitude;
            
            % Filter the audio signal
            filteredAudio = filter(h, 1, audioData);
            
            % Plot the audio signal in time domain
            plot(app.UIAxes_4, t, filteredAudio);
            xlim(app.UIAxes_4, [0, max(t)]);
            ylim(app.UIAxes_4, [-1, 1]);
            grid(app.UIAxes_4, 'on');
            
            % Perform FFT to get the frequency domain representation
            n = length(filteredAudio);
            f = (0:n-1)*(fs/n) / 1000;
            
            y_original = fft(audioData);
            magnitude_original = abs(y_original);
            y = fft(filteredAudio);
            magnitude = abs(y);
            
            % Convert magnitude to dBm
            reference_power = 1; % 1 milliwatt
            power_original = (magnitude_original.^2) / n; % Power calculation
            power_dBm_original = 10 * log10(power_original / reference_power); % Convert to dBm
            power = (magnitude.^2) / n; % Power calculation
            power_dBm = 10 * log10(power / reference_power); % Convert to dBm
    
            % Plot the audio signal in frequency domain
            plot(app.UIAxes_3, f, magnitude);
            xlim(app.UIAxes_3, [0, fs/2000]);
            xticks(app.UIAxes_3, 0:1:fs/2000);
            xticklabels(app.UIAxes_3, string(0:1:fs/2000));
            ylim(app.UIAxes_3, [0, max_magnitude]);
            grid(app.UIAxes_3, 'on');
            
            plot(app.UIAxes_6, f, power_dBm_original, 'Visible', 'off');
            xlim(app.UIAxes_6, [0, fs/2000]);
            xticks(app.UIAxes_6, 0:1:fs/2000);
            xticklabels(app.UIAxes_6, string(0:1:fs/2000));
            ylim(app.UIAxes_6, [min(min(power_dBm)), max(max(power_dBm_original))]);
            grid(app.UIAxes_6, 'on');
            
            plot(app.UIAxes_5, f, power_dBm, 'Visible', 'off');
            xlim(app.UIAxes_5, [0, fs/2000]);
            xticks(app.UIAxes_5, 0:1:fs/2000);
            xticklabels(app.UIAxes_5, string(0:1:fs/2000));
            ylim(app.UIAxes_5, [min(min(power_dBm)), max(max(power_dBm_original))]);
            grid(app.UIAxes_5, 'on');
                
            app.StatusEditField_2.Value = "Filtering process successful.";
            
            % Create an audioplayer object
            app.audioplayerObjF = audioplayer(filteredAudio, fs);
            
            % Enable Play and Stop button
            app.PlayFilteredAudioButton.Enable = 'on';
            app.StopButton_2.Enable = 'on';
            app.MagnitudeindBmButton.Enable = 'on';
            app.TimeandFrequncyButton.Enable = 'on';
        end

        % Button pushed function: PlayFilteredAudioButton
        function PlayFilteredAudioButtonPushed(app, event)
            % Play the audioplayer object
            play(app.audioplayerObjF);
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            % Stop the audioplayer object
            stop(app.audioplayerObjF);
        end

        % Selection changed function: GraphModeButtonGroup
        function GraphModeButtonGroupSelectionChanged(app, event)
            % Switch axes
            selectedButton = app.GraphModeButtonGroup.SelectedObject;
            
            if selectedButton == app.MagnitudeindBmButton
                app.UIAxes_4.Visible = 'off';
                app.UIAxes_3.Visible = 'off';
                set(app.UIAxes_3.Children, 'Visible', 'off');
                set(app.UIAxes_4.Children, 'Visible', 'off');
                app.UIAxes_6.Visible = 'on';
                app.UIAxes_5.Visible = 'on';
                set(app.UIAxes_6.Children, 'Visible', 'on');
                set(app.UIAxes_5.Children, 'Visible', 'on');
            else
                app.UIAxes_3.Visible = 'on';
                app.UIAxes_4.Visible = 'on';
                set(app.UIAxes_3.Children, 'Visible', 'on');
                set(app.UIAxes_4.Children, 'Visible', 'on');
                app.UIAxes_5.Visible = 'off';
                app.UIAxes_6.Visible = 'off';
                set(app.UIAxes_5.Children, 'Visible', 'off');
                set(app.UIAxes_6.Children, 'Visible', 'off');
            end
        end

        % Value changed function: CutoffFrequencyEditField
        function CutoffFrequencyEditFieldValueChanged(app, event)
            value = app.CutoffFrequencyEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FIRBandPassFilterLabel = uilabel(app.UIFigure);
            app.FIRBandPassFilterLabel.Text = 'FIR Band-Pass Filter ';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AudioInputTab = uitab(app.TabGroup);
            app.AudioInputTab.Title = 'Audio Input';
            app.UIAxes = uiaxes(app.AudioInputTab);
            title(app.UIAxes, 'Original Audio in Frequency Domain')
            xlabel(app.UIAxes, 'Frequency (kHz)')
            ylabel(app.UIAxes, 'Magnitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.AudioInputTab);
            title(app.UIAxes_2, 'Original Audio in Time Domain')
            xlabel(app.UIAxes_2, 'Time (s)')
            ylabel(app.UIAxes_2, 'Magnitude')
            zlabel(app.UIAxes_2, 'Z')
            app.InputAudioLabel = uilabel(app.AudioInputTab);
            app.InputAudioLabel.Text = 'Input Audio';
            app.SelectFileButton = uibutton(app.AudioInputTab, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.StatusEditField = uieditfield(app.AudioInputTab, 'text');
            app.StatusEditField.Editable = 'off';
            app.StatusEditField.Value = 'No audio input.';
            app.StatusEditFieldLabel = uilabel(app.AudioInputTab);
            app.StatusEditFieldLabel.Text = 'Status';
            app.PlayUnfilteredAudioButton = uibutton(app.AudioInputTab, 'push');
            app.PlayUnfilteredAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayUnfilteredAudioButtonPushed, true);
            app.PlayUnfilteredAudioButton.Enable = 'off';
            app.PlayUnfilteredAudioButton.Text = 'Play Unfiltered Audio';
            app.CompressionMethodEditField = uieditfield(app.AudioInputTab, 'text');
            app.CompressionMethodEditField.Editable = 'off';
            app.CompressionMethodEditFieldLabel = uilabel(app.AudioInputTab);
            app.CompressionMethodEditFieldLabel.Text = 'Compression Method';
            app.kHzLabel = uilabel(app.AudioInputTab);
            app.kHzLabel.Text = 'kHz';
            app.StopButton = uibutton(app.AudioInputTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.SamplingFrequencyEditFieldLabel = uilabel(app.AudioInputTab);
            app.SamplingFrequencyEditFieldLabel.Text = 'Sampling Frequency';
            app.SamplingFrequencyEditField = uieditfield(app.AudioInputTab, 'numeric');
            app.SamplingFrequencyEditField.Editable = 'off';
            app.FilterDesignTab = uitab(app.TabGroup);
            app.FilterDesignTab.Title = 'Filter Design';
            app.UIAxes2 = uiaxes(app.FilterDesignTab);
            title(app.UIAxes2, 'Filter Magnitude Response')
            xlabel(app.UIAxes2, 'Frequency (kHz)')
            ylabel(app.UIAxes2, 'Magnitude (dB)')
            zlabel(app.UIAxes2, 'Z')
            app.WindowDropDownLabel = uilabel(app.FilterDesignTab);
            app.WindowDropDownLabel.Text = 'Window';
            app.WindowDropDown = uidropdown(app.FilterDesignTab);
            app.WindowDropDown.Items = {'Rectangular', 'Hann', 'Hamming', 'Blackman'};
            app.WindowDropDown.Value = 'Rectangular';
            app.CutoffFrequencyLabel = uilabel(app.FilterDesignTab);
            app.CutoffFrequencyLabel.Text = 'Cut-off Frequency';
            app.CutoffFrequencyEditField = uieditfield(app.FilterDesignTab, 'numeric');
            app.CutoffFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @CutoffFrequencyEditFieldValueChanged, true);
            app.OrderEditFieldLabel = uilabel(app.FilterDesignTab);
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.FilterDesignTab, 'numeric');
            app.kHzLabel_2 = uilabel(app.FilterDesignTab);
            app.kHzLabel_2.Text = 'kHz';
            app.DesignFilterButton = uibutton(app.FilterDesignTab, 'push');
            app.DesignFilterButton.ButtonPushedFcn = createCallbackFcn(app, @DesignFilterButtonPushed, true);
            app.DesignFilterButton.Enable = 'off';
            app.DesignFilterButton.Text = 'Design Filter';
            app.kHzLabel_3 = uilabel(app.FilterDesignTab);
            app.kHzLabel_3.Text = 'kHz';
            app.kHzLabel_4 = uilabel(app.FilterDesignTab);
            app.kHzLabel_4.Text = 'kHz';
            app.SamplingFrequencyEditField_2Label = uilabel(app.FilterDesignTab);
            app.SamplingFrequencyEditField_2Label.Text = 'Sampling Frequency';
            app.SamplingFrequencyEditField_2 = uieditfield(app.FilterDesignTab, 'numeric');
            app.SamplingFrequencyEditField_2.Editable = 'off';
            app.CutoffFrequencyEditField_2 = uieditfield(app.FilterDesignTab, 'numeric');
            app.BandpassFilterDesignLabel = uilabel(app.FilterDesignTab);
            app.BandpassFilterDesignLabel.Text = 'Bandpass Filter Design';
            app.AudioOutputTab = uitab(app.TabGroup);
            app.AudioOutputTab.Title = 'Audio Output';
            app.UIAxes_3 = uiaxes(app.AudioOutputTab);
            title(app.UIAxes_3, 'Filtered Audio in Frequency Domain')
            xlabel(app.UIAxes_3, 'Frequency (kHz)')
            ylabel(app.UIAxes_3, 'Magnitude')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.AudioOutputTab);
            title(app.UIAxes_4, 'Filtered Audio in Time Domain')
            xlabel(app.UIAxes_4, 'Time (s)')
            ylabel(app.UIAxes_4, 'Amplitude')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.AudioOutputTab);
            title(app.UIAxes_5, 'Filtered Audio Magnitude in dBm')
            xlabel(app.UIAxes_5, 'Frequency (kHz)')
            ylabel(app.UIAxes_5, 'Magnitude (dBm)')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_5.Visible = 'off';
            app.UIAxes_6 = uiaxes(app.AudioOutputTab);
            title(app.UIAxes_6, 'Unfiltered Audio Magnitude in dBm')
            xlabel(app.UIAxes_6, 'Frequency (kHz)')
            ylabel(app.UIAxes_6, 'Magnitude (dBm)')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_6.Visible = 'off';
            app.ApplyDesignedFilterButton = uibutton(app.AudioOutputTab, 'push');
            app.ApplyDesignedFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyDesignedFilterButtonPushed, true);
            app.ApplyDesignedFilterButton.Enable = 'off';
            app.ApplyDesignedFilterButton.Text = 'Apply Designed Filter';
            app.StatusEditField_2 = uieditfield(app.AudioOutputTab, 'text');
            app.StatusEditField_2.Editable = 'off';
            app.StatusEditField_2.Value = 'No filter applied.';
            app.StatusEditField_2Label = uilabel(app.AudioOutputTab);
            app.StatusEditField_2Label.Text = 'Status';
            app.PlayFilteredAudioButton = uibutton(app.AudioOutputTab, 'push');
            app.PlayFilteredAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayFilteredAudioButtonPushed, true);
            app.PlayFilteredAudioButton.Enable = 'off';
            app.PlayFilteredAudioButton.Text = 'Play Filtered Audio';
            app.StopButton_2 = uibutton(app.AudioOutputTab, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Enable = 'off';
            app.StopButton_2.Text = 'Stop';
            app.GraphModeButtonGroup = uibuttongroup(app.AudioOutputTab);
            app.GraphModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GraphModeButtonGroupSelectionChanged, true);
            app.GraphModeButtonGroup.Title = 'Graph Mode';
            app.TimeandFrequncyButton = uiradiobutton(app.GraphModeButtonGroup);
            app.TimeandFrequncyButton.Enable = 'off';
            app.TimeandFrequncyButton.Text = 'Time and Frequncy';
            app.TimeandFrequncyButton.Value = true;
            app.MagnitudeindBmButton = uiradiobutton(app.GraphModeButtonGroup);
            app.MagnitudeindBmButton.Enable = 'off';
            app.MagnitudeindBmButton.Text = 'Magnitude in dBm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinproSinsisMatlabSelesai

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end